classdef test_checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        OutputTextArea            matlab.ui.control.TextArea
        TextAreaLabel             matlab.ui.control.Label
        NMCGrDropDown_2           matlab.ui.control.DropDown
        NMCGrDropDown             matlab.ui.control.DropDown
        NMCGrDropDownLabel        matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        BatteryTypeDropDown       matlab.ui.control.DropDown
        BatteryTypeDropDownLabel  matlab.ui.control.Label
        ProcessTypeDropDown       matlab.ui.control.DropDown
        ProcessTypeDropDownLabel  matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        Label                     matlab.ui.control.Label
        AnalyzeButton             matlab.ui.control.Button
        ChooseFileButton          matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Data
        test_result
        Chimestry
        Size
        Kind
        Remark
        files
        files_size
        files_error
        a1 % Description
        PlotFigure % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ChooseFileButtonPushed(app, event)
              % Button pushed function: ChooseFileButton
        % 
            [filenames, pathname] = uigetfile('*.*', 'Select files', 'MultiSelect', 'on');
            % Check if the user clicked Cancel in the file dialog
            if isequal(filenames, 0) || isequal(pathname, 0)
                msgbox('File selection canceled.');
                return; % Exit the script if the user cancels file selection
            end

            % Handle the case where only one file is selected
            if ischar(filenames)
                filenames = {filenames};
            end

            % Initialize cell arrays to store data
            data = {};
            data2 = {};
            data3 = {};

            % Iterate over each selected file
            for i = 1:numel(filenames)
                fullFilePath = fullfile(pathname, filenames{i});

                % Read all sheets in the Excel file
                [~, sheetNames] = xlsfinfo(fullFilePath);

                % Iterate over each sheet
                for j = 1:numel(sheetNames)
                    % Check if the sheet name starts with 'Detail_'
                    if startsWith(sheetNames{j}, 'Detail_')
                        % Read data from the sheet
                        data{end+1} = readtable(fullFilePath, 'Sheet', sheetNames{j});
                    elseif startsWith(sheetNames{j}, 'Statis_')
                        % Read data from the corresponding 'Statis_' sheet
                        data2{end+1} = readtable(fullFilePath, 'Sheet', sheetNames{j});
                    end
                end
                sheet3 = 'Info';
                data3{end+1} = readtable(fullFilePath, 'Sheet', sheet3);
            end

            app.Data = {data, data2, data3};
        end

        % Button pushed function: AnalyzeButton
        function Analyze(app, event)
        database2 = app.a1.value;  
    database = app.Data;

    numFiles = numel(app.files_size(:, 1));
    numRows = ceil(sqrt(numFiles));  % Number of rows for subplots
    numCols = ceil(numFiles / numRows);  % Number of columns for subplots
    text1 = cell(1, 11);
    for k = 1:numFiles
        % Get the full file path
        fullPath = app.files_size(k).file;

        % Extract the file name without extension
        [~, name, ~] = fileparts(fullPath);
        Name = name;
        name_of_files = name;
        name = cell(numFiles, 2);

        % Extract the time Duration for each step ID
        timeStrings = database2(k,:).Time; %NDA 
        timeDurations = timeStrings;
        time = timeDurations;
        current = database2(k,:).Current_mA_ / 1000;
        voltage = database2(k,:).Voltage;

        t = zeros(length(current), 1);
        t(1,1) = 0;
        r = zeros(length(current), 1);
        r(1) = voltage(2) - voltage(1) / current(2) - current(1);
        for i = 2:length(current)
            t(i,1) = t(i-1,1) + 30;
            r(i) = abs(voltage(i) - voltage(i-1) / current(i) - current(i-1));
        end

        time_index = find(time == 0);
        time_duration_hours = 30 * diff(time_index) / 3600; % NDA
        time_duration_step = 30 * diff(time_index); % NDA
        time_duration = time_duration_step;
        time_duration(end + 1) = t(end) - time_index(end) * 30;
        time_duration = num2cell(time_duration);

        c = zeros(length(time_index), 1);
        x = cell(length(time_index), 1);
        for i = 1:length(c)
            c(i, 1) = current(time_index(i));
            x(i, 1) = database2(k,:).Status(time_index(i));
        end
        step_kind = [num2cell(x(:, 1)), time_duration(:, 1), num2cell(c(:, 1))];
        for i = 1:numel(text1)
             text1{i} = [];
        end

        plotNeeded = false;  % Flag to check if plotting is needed
        inx = zeros(length(ceil(time_index / 2)), 2);
        counter = 0;
        
        % fig(k) = figure('Name','Error Plot');
        end_cycle = max(database2(k,:).Cycle);
        if 5>end_cycle
        a = ('Formation is not done');
        text1{1} = a;
        plotNeeded = true;
        end
        if length(database2(k,:).Time) < 50
        a = ('Formation has failed, There is a break in the circuit');
        text1{1} = a;
        plotNeeded = true;
        end
        switch(app.Chimestry)

            case 1 % NMC -Gr
                for i = 1:length(step_kind)
                    index = time_index(i);
                    if strcmp(step_kind{i, 1}, 'CCCV_Chg') % if we are in stage CCCV_Chg than start analyzing
                        dur_time = step_kind{i, 2};
                        
                        % Determine the end index (index2)
                        if i < length(step_kind)
                            index2 = time_index(i + 1) - 1; % End just before the next step
                            option_run = 0;
                            
                            if dur_time > 54000 % 15 hours % Check Duration of CCCV
                                a = ['CCCV_Chg, Step ID:', num2str(i), '--- duration of charge is too long'];
                                text1{1} = a;
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name(k, 1) = Name;
                                % name(k, 2) = a;
                            end

                            % Check Erez Rule
                            voltage_drop_threshold = 0.001; % Adjust as needed
                            current_constancy_threshold = 0.01; % Adjust as needed
                            current_diff = abs(diff(current(index + 2:index2)));

                            % Check if the current is constant and above zero
                            is_current_constant_and_above_zero = all(current_diff <= current_constancy_threshold) && all(current(index + 2:index2) >= 0);
                            window_size = 5;
                            smoothed_voltage = smoothdata(voltage(index + 2:index2), 'movmean', window_size);
                            voltage_derivative = diff(smoothed_voltage);

                            % Check if the voltage drops significantly
                            is_voltage_dropping = any(voltage_derivative < -voltage_drop_threshold);

                            % Check if both conditions are met
                            if max(voltage(index:index2)) < 4.3 - 0.05
                                a = ['CCCV_Chg, Step ID:', num2str(i), '--is not good Voltage does not reach the required voltage'];
                                text1{2} = a;
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = a;
                            elseif is_current_constant_and_above_zero && is_voltage_dropping
                                a = 'Battery formation may be problematic: Current is constant and above zero, and voltage is dropping significantly.';
                                text1{3} = a;
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = a;
                            end
                        else
                            index2 = length(time); % End at the end of the data.
                            option_run = 1;
                        end
                        current_step = step_kind{i, 3};
                        if any(current(index:index2) > current_step + 0.01) % needs to be changed and reconsider if necessary
                            a = 'CCCV_Chg, The current of the battery failed to reach the desired value ';
                            text1{4} = a;
                            plotNeeded = true;
                            counter = counter + 1;
                            inx(counter, 1) = index;
                            inx(counter, 2) = index2;
                            % name{k, 1} = Name;
                            % name{k, 2} = a;
                        end

                        % Smooth the current data using a moving average filter
                        window_size = 5; % Adjust if needed , probably this is excellent fit
                        smoothed_current = smoothdata(current, 'movmean', window_size);
                        dt = diff(time); % Compute the time differences
                        d_smoothed_current = diff(smoothed_current); % Compute the central differences for the smoothed current
                        smoothed_current_derivative = d_smoothed_current ./ dt; % Calculate the derivative of the smoothed current with respect to time

                        % Check if CV stage does not happen
                        if all(smoothed_current_derivative(index + 2:index2) < 9e-06) && option_run 
                            a = 'CCCV_Chg Faild , Could not reach the CV stage in the CCCV';
                            text1{4} = a;
                            plotNeeded = true;
                            counter = counter + 1;
                            inx(counter, 1) = index;
                            inx(counter, 2) = index2;
                            % name{k, 1} = Name;
                            % name{k, 2} = a;
                        end

                        % Check BATH Rule
                        [~, ind] = min(current(index:index2));
                        check_monotonically = find(current(index:index2) < (current_step - 0.05));
                        option_cancel_CV = 0;

                        if ~isempty(check_monotonically)
                            range = find(diff(voltage(index:index + check_monotonically(1))) < 0);
                            if length(range) > 5
                                if all(diff(range) <= 2)
                                    a = ['CCCV_Chg failed, step ID: ', num2str(i), ' The voltage decreasing while current is positive'];
                                    text1{5} = a;
                                    plotNeeded = true;
                                    counter = counter + 1;
                                    inx(counter, 1) = index;
                                    inx(counter, 2) = index2;
                                    % name{k, 1} = Name;
                                    % name{k, 2} = a;
                                end
                            end
                        elseif any(current(ind + index:index2) < (current_step - 0.05) & isempty(check_monotonically))
                            a = 'CCCV_Chg faild, The current is not constant';
                            text1{5} = a;
                            plotNeeded = true;
                            counter = counter + 1;
                            inx(counter, 1) = index;
                            inx(counter, 2) = index2;
                            % name{k, 1} = Name;
                            % name{k, 2} = a;
                        else
                            if all(voltage(ind + index - 1:index2) < 4.29)
                                a = 'CV stage has failed , The Voltage is not constant';
                                option_cancel_CV = 1;
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = a;
                            end
                            v_ind = find(voltage(index:index2) >= 4.29);
                            if option_cancel_CV
                                a = "CV stage in CCCV failed, the voltage is not constant";
                            elseif length(v_ind) < 15
                                a = "CV stage too short duration";
                                CV_long = length(v_ind) * 30 / 60;  
                                text1{7} = ["CV stage too long duration, The length of the CV is:", CV_long, "Minutes"];
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = a;
                            elseif length(v_ind) > 180
                                a = "CV stage too long duration";
                                CV_long = length(v_ind) * 30 / 60;  
                                text1{8} = ["CV stage too long duration, The length of the CV is:", CV_long, "Minutes"];
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = text1;
                            end
                        end
                    end
                    if strcmp(step_kind{i, 1}, 'CC_DChg')
                        dur_time = step_kind{i, 2};
                        current_step = step_kind{i, 3};
                        if i < length(step_kind)
                            index2 = time_index(i + 1) - 1; % End just before the next step
                            if dur_time > 54000 % 5 hours % Check Duration of CC_DChg
                                text1{10} = 'CC_DChg, Step ID number is not good';
                                plotNeeded = true;
                                counter = counter + 1;
                                inx(counter, 1) = index;
                                inx(counter, 2) = index2;
                                % name{k, 1} = Name;
                                % name{k, 2} = text1;
                            end
                        else
                            index2 = length(time_index); % End at the end of the data.
                        end
                        if any(current(index:index2) > current_step + 0.01) % needs to be changed and reconsider if necessary
                            text1{11} = 'CC_DChg, Step ID number is not good , The current of the battery failed to reach the desired value ';
                            plotNeeded = true;
                            counter = counter + 1;
                            inx(counter, 1) = index;
                            inx(counter, 2) = index2;
                            % name{k, 1} = Name;
                            % name{k, 2} = text1;
                        end

                        voltage_in_cc_dchg = voltage(index:index2);
                        if any(voltage_in_cc_dchg(1:end - 1) < voltage_in_cc_dchg(2:end))
                            text1{12} = 'CC_DChg,  is not good - Voltage should only decrease during CC_DChg.';
                            plotNeeded = true;
                            counter = counter + 1;
                            inx(counter, 1) = index;
                            inx(counter, 2) = index2;
                            % name{k, 1} = Name;
                            % name{k, 2} = text1;
                        end
                    end
                end

            case 2
        for i =1:length(step_kind)
        index = time_index(i);
    % Note:'Rest' condition is not entered here cause the structure of formation battery is not constant
        if strcmp(step_kind{i, 1}, 'CCCV_Chg') % if we are in stage CCCV_Chg than start analyzing
                dur_time = step_kind{i, 2};

                  % Determine the end index (index2)
                if i < length(step_kind)
                    index2 = time_index(i+1) - 1; % End just before the next step
                    option_run=0;
                   if dur_time > 54000 %% 15 hours %% Check Duration of CCCV
                       a=['CCCV_Chg, Step ID:', num2str(i), '--- duration of charge is too long'];
                       text1{1}=a;
                       % plot_error(index,index2,voltage,current,t,Name,text1(1),fig,i);
                                              plotNeeded = true;
                                             counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=text1;
                       
                   end
                   % Check Erez Rule
                    % Threshold for voltage drop and current constancy
                    voltage_drop_threshold = 0.001; % Adjust as needed
                    current_constancy_threshold = 0.01; % Adjust as needed
                    current_diff = abs(diff(current(index+2:index2)));

                    % Check if the current is constant and above zero
                    is_current_constant_and_above_zero = all(current_diff <= current_constancy_threshold) && all(current(index+2:index2) >= 0);
                    window_size = 5;
                    smoothed_voltage = smoothdata(voltage(index+2:index2), 'movmean', window_size);
                    voltage_derivative = diff(smoothed_voltage);

                    % Check if the voltage drops significantly
                    is_voltage_dropping = any(voltage_derivative < -voltage_drop_threshold);

                    % Check if both conditions are met
                    if max(voltage(index:index2)) < 4.3
                       % number_ID=num2str(i);
                       a = ['CCCV_Chg, Step ID:',num2str(i),'--is not good Voltage does not reach the required voltage'];
                       text1{2} = a;
                       % plot_error(index,index2,voltage,current,t,Name,text1{2},fig,i);
                       plotNeeded = true;
                       counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                       
                       % msgbox(text,'Error in Formation')
                   
                    elseif is_current_constant_and_above_zero && is_voltage_dropping
                        a = 'Battery formation may be problematic: Current is constant and above zero, and voltage is dropping significantly.';
                        text1{2} =a;
                       plotNeeded = true;
                       ounter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                        % plot_error(index,index2,voltage,current,t,Name,text1{2},fig,i);
                    end
                else
                    index2 = length(time); % End at the end of the data.
                    option_run = 1;
                end
             current_step = step_kind{i, 3};
                if any(current(index:index2)>current_step+0.01)  %% needs to be changed and reconsider if necessary %%
                    a = 'CCCV_Chg, The current of the battery failed to reach the desired value ';
                    text1{3} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                    % plot_error(index,index2,voltage,current,t,Name,text1(3),fig,i);
                end   


                % Smooth the current data using a moving average filter
                window_size = 5; % Adjust if needed , probably this is excellent fit
                smoothed_current = smoothdata(current, 'movmean', window_size);
                % Compute the time differences
                dt = diff(time);
                
                % Compute the central differences for the smoothed current
                d_smoothed_current = diff(smoothed_current);
                
                % Calculate the derivative of the smoothed current with respect to time
                % Using element-wise division to handle variable time intervals
                smoothed_current_derivative = d_smoothed_current ./ dt;
                 % plot(smoothed_current_derivative)

                    % Check if CV stage does not happen 
                    if all(smoothed_current_derivative(index+2:index2) < 9e-06) && option_run 
                        a = 'CCCV_Chg Faild , Could not reach the CV stage in the CCCV';
                        text1{4} = a;
                                               plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=text1;
                         % plot_error(index,index2,voltage,current,t,Name,text1(4),fig,i);
                    end
                    % Check BATH Rule
                   [~,ind]=min(current(index:index2));
                   check_monotonically=find(current(index:index2) < (current_step-0.05));
                   option_cancel_CV = 0;

                   if ~isempty(check_monotonically)
                       range = find(diff(voltage(index:index+check_monotonically(1)))<0);
                       if length(range) > 5
                            if all(diff(range) <=2)
                              a = ['CCCV_Chg failed, step ID: ',num2str(i),' The voltage decreasing while current is positive'];
                              text1{5} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                               % plot_error(index,index+check_monotonically(1),voltage,current,t,Name,text1(5),fig,i);
                            end


                   elseif(any(current(ind+index:index2) < (current_step-0.05) & isempty(check_monotonically)))
                       a = 'CCCV_Chg faild, The current is not constant';
                       text1{5} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                       % plot_error(index+ind,index2,voltage,current,t,Name,text1(5),fig,i); 
                       
                       
                   else
                        %Entered CV stage
                        if all(voltage(ind+index-1:index2) < 4.3)
                            a = 'CV stage has failed , The Voltage is not constant';
                            text1{6} =a;
                            option_cancel_CV = 1;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index+ind,index2,voltage,current,t,Name,text1(6),fig,i);
                        end
                        v_ind = find(voltage(index:index2) >= 4.29);
                        % s=any(diff(v_ind) > 2); % if =1 then voltage is not constant
                        if (option_cancel_CV)
                            a="CV stage in CCCV failed, the voltage is not constant";
                            text1{6} =a;

                        elseif length(v_ind) < 15
                            a = "CV stage too short duration";
                            text1{6} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index,index2,voltage,current,t,Name,text1(6),fig,i);


                        elseif length(v_ind) > 180
                            a = "CV stage too long duration";
                            text1{6} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index+v_ind(1),index2,voltage,current,t,Name,text1(6),fig,i);
                            
                            
                        end
                   end
                   end


    end
     if strcmp(step_kind{i, 1}, 'CC_DChg') % String Compare
            dur_time = step_kind{i, 2};
             
                current_step = step_kind{i, 3};
                  % Determine the end index (index2)
                if i < length(step_kind)
                    index2 = time_index(i+1) - 1; % End just before the next step
                    
                    if dur_time > 54000 %% 5 hours %% Check Duration of CC_DChg
                        text1 ='CC_DChg, Step ID number is not good';
                                               plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                        % plot_error(index,index2,voltage,current,t,Name,text1,fig,i);
                    end
                else
                    index2 = length(time_index); % End at the end of the data.
                    
                end
                if any(current(index:index2)>current_step+0.01)  %% needs to be changed and reconsider if necessary %%
                    text1 ='CC_DChg, Step ID number is not good , The current of the battery failed to reach the desired value ';
                                           plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=text1;
                    % plot_error(index,index2,voltage,current,t,Name,text1,fig,i);
                end 
               
                voltage_in_cc_dchg = voltage(index:index2);
                if any(voltage_in_cc_dchg(1:end-1) < voltage_in_cc_dchg(2:end))
                     text1 ='CC_DChg,  is not good - Voltage should only decrease during CC_DChg.';
                                            plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=text1;
                    % plot_error(index,index2,voltage,current,t,Name,text1,fig,i);
                end
     end

    end
   
        % % Remove empty elements from the cell array
        % message = text1(~cellfun('isempty', text1));
        % message1 = sprintf('%s\n', message{:});
        % % Create the message box
        % 
        % h = msgbox(['The Faults in the Formation are:' newline message1], 'Test Result', 'error');
        % 
        % % Find the text object within the message box
        % hText = findall(h, 'Type', 'Text');
        % 
        % % Set the font size
        % set(hText, 'FontSize', 10);  % Adjust the font size as needed
        % % h = msgbox(['The Faults in the Formation are:' newline message1], 'Test Result', 'error');
        % 
        % % Set the position and size of the message box window
        % % Adjust the values as needed to increase the size
        % newPosition = get(h, 'Position');
        % newPosition(3:4) = [400, 220]; % Width and height of the message box window
        % set(h, 'Position', newPosition);
        % % msgbox(['The Faults in the Formation are:' newline message1],'Test Result',"error");
        % % disp(data2)
        % % fig = uifigure('Name','Reporting Results');
        % % fig.Position = [20 100 2100 370];
        % % uit = uitable(fig,"Data",data2(:,["Channel","CyCle","Step","RawStepID","Status","CapaCity_mAh_"]),"Position",[25 25 2000 320]);
        % app.test_result=createResultTable(data2{1,1});
        % % fig = uifigure('Name','Capacity and Efficiency in each Cycle');
        % % fig.Position = [20 100 500 200];
        % % uit = uitable(fig,"Data",app.test_result,"Position",[50 50 400 150]);    

            case 3
                        for i =1:length(step_kind)
        index = time_index(i);
    % Note:'Rest' condition is not entered here cause the structure of formation battery is not constant
        if strcmp(step_kind{i, 1}, 'CCCV_Chg') % if we are in stage CCCV_Chg than start analyzing
                dur_time = step_kind{i, 2};
                  % Determine the end index (index2)
                if i < length(step_kind)
                    index2 = time_index(i+1) - 1; % End just before the next step
                    option_run=0;
                    
                   if dur_time > 54000 %% 15 hours %% Check Duration of CCCV
                       a=['CCCV_Chg, Step ID:', num2str(i), '--- duration of charge is too long'];
                       text1{1}=a;
                                              plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                       % plot_error(index,index2,voltage,current,t,Name,text1(1),fig,i);
                       
                       
                   end
                   % Check Erez Rule
                    % Threshold for voltage drop and current constancy
                    voltage_drop_threshold = 0.001; % Adjust as needed
                    current_constancy_threshold = 0.01; % Adjust as needed
                    current_diff = abs(diff(current(index+2:index2)));

                    % Check if the current is constant and above zero
                    is_current_constant_and_above_zero = all(current_diff <= current_constancy_threshold) && all(current(index+2:index2) >= 0);
                    window_size = 5;
                    smoothed_voltage = smoothdata(voltage(index+2:index2), 'movmean', window_size);
                    voltage_derivative = diff(smoothed_voltage);

                    % Check if the voltage drops significantly
                    is_voltage_dropping = any(voltage_derivative < -voltage_drop_threshold);

                    % Check if both conditions are met
                    if max(voltage(index:index2)) < 3.79
                       % number_ID=num2str(i);
                       xd = ['CCCV_Chg, Step ID:',num2str(i),'--is not good Voltage does not reach the required voltage'];
                       text1{2} = xd;
                                              plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                       % plot_error(index,index2,voltage,current,t,Name,text1{2},fig,i);
                       
                       
                       % msgbox(text,'Error in Formation')
                   
                    elseif is_current_constant_and_above_zero && is_voltage_dropping
                        a = 'Battery formation may be problematic: Current is constant and above zero, and voltage is dropping significantly.';
                        text1{2} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                        % plot_error(index,index2,voltage,current,t,Name,text1{2},fig,i);
                    end
                else
                    index2 = length(time); % End at the end of the data.
                    option_run = 1;
                end
             current_step = step_kind{i, 3};
                if any(current(index:index2)>current_step+0.01)  %% needs to be changed and reconsider if necessary %%
                    % a = 'CCCV_Chg, The current of the battery failed to reach the desired value ';
                    % text1{1,3} =a;
                    text1='CCCV_Chg, The current of the battery failed to reach the desired value ';
                                           plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                    % plot_error(index,index2,voltage,current,t,Name,text1(3),fig,i);
                end   


                % Smooth the current data using a moving average filter
                window_size = 5; % Adjust if needed , probably this is excellent fit
                smoothed_current = smoothdata(current, 'movmean', window_size);
                % Compute the time differences
                dt = diff(time);
                
                % Compute the central differences for the smoothed current
                d_smoothed_current = diff(smoothed_current);
                
                % Calculate the derivative of the smoothed current with respect to time
                % Using element-wise division to handle variable time intervals
                smoothed_current_derivative = d_smoothed_current ./ dt;
                 % plot(smoothed_current_derivative)

                    % Check if CV stage does not happen 
                    if all(smoothed_current_derivative(index +2 :(index2-1)) < 9e-06) && option_run 
                        a = 'CCCV_Chg Faild , Could not reach the CV stage in the CCCV';
                        text1{4} = a;
                                               plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                         % plot_error(index,index2,voltage,current,t,Name,text1(4),fig,i);
                    end
                    % Check BATH Rule
                   [~,ind]=min(current(index:index2));
                   check_monotonically=find(current(index:index2) < (current_step-0.05));
                   option_cancel_CV = 0;

                   if ~isempty(check_monotonically)
                       range = find(diff(voltage(index:index+check_monotonically(1)))<0);
                       if length(range) > 5
                            if all(diff(range) <=2)
                              a = ['CCCV_Chg failed, step ID: ',num2str(i),' The voltage decreasing while current is positive'];
                              text1{5} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                               % plot_error(index,index+check_monotonically(1),voltage,current,t,Name,text1(5),fig,i);
                            else
                             v_ind = find(voltage(index:index2) >= 3.79);
                            % s=any(diff(v_ind) > 2); % if =1 then voltage is not constant
                            
                                if (option_cancel_CV)
                                    a="CV stage in CCCV failed, the voltage is not constant";
                                    text1{6} =a;

                                elseif length(v_ind) < 15
                                    a = "CV stage too short duration";
                                  text1{6} =a;
                                                          plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                                    % plot_error(index,index2,voltage,current,t,Name,text1,fig,i);


                                elseif length(v_ind) > 180
                                    a = "CV stage too long duration";
                                    text1{6} =a;
                                                           plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                                    % plot_error(index+v_ind(1),index2,voltage,current,t,Name,text1,fig,i);
                            
                            
                                end    

                            end


                   elseif(any(current(ind+index:index2) < (current_step-0.05) & isempty(check_monotonically)))
                       a = 'CCCV_Chg faild, The current is not constant';
                       text1{7} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                       % plot_error(index+ind,index2,voltage,current,t,Name,text1(5),fig,i); 
                       
                       
                   else
                        %Entered CV stage
                        if all(voltage(ind+index-1:index2) < 3.79-0.05)
                            a = 'CV stage has failed , The Voltage is not constant';
                            text1{7} =a;
                            option_cancel_CV = 1;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index+ind,index2,voltage,current,t,Name,text1(6),fig,i);
                        end
                        v_ind = find(voltage(index:index2) >= 3.79);
                        % s=any(diff(v_ind) > 2); % if =1 then voltage is not constant
                        if (option_cancel_CV)
                            a="CV stage in CCCV failed, the voltage is not constant";
                            ttext1{7} =a;

                        elseif length(v_ind) < 5
                            a = "CV stage too short duration";
                            text1{7} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index,index2,voltage,current,t,Name,text1,fig,i);


                        elseif length(v_ind) > 180
                            a = "CV stage too long duration";
                            text1{8} =a;
                       plotNeeded = true;
                                              counter=counter+1;
                       inx(counter,1)=index;inx(counter,2)=index2;%name{k,1}=Name;name{k,2}=a;
                             % plot_error(index+v_ind(1),index2,voltage,current,t,Name,text1(6),fig,i);
                            
                            
                        end
                   end
                   end


    end

    end
        end

                fig(k) = uifigure('Position', [100, 100, 1000, 700]); % Adjust position and size as needed
                ax = uiaxes(fig(k), 'Position', [50, 450, 900, 200]);
                
                
                    % Extract string elements from the cell array
                    text2=text1;
                    stringElements = text2(cellfun(@ischar, text2));
                    
                    % Concatenate the string elements with newline characters
                    message1 = strjoin(stringElements, '\n');
                    Name = name_of_files;
                    % Plot errors if any
                    validInx = inx(~all(inx == 0, 2), :);
                    for i = 1:height(validInx)
                        

                        index = validInx(i, 1);
                        index2 = validInx(i, 2);
                        % index = inx(i, 1);
                        % index2 = inx(i, 2);
                        
                        % if index && index2
                          colors = lines(length(step_kind)); % Generate a colormap
                          plot(ax, t/3600, voltage, 'Color', colors(i, :)); hold(ax, 'on');
                          plot(ax, t/3600, current, 'Color', 'r', 'LineStyle', '-');
                        if ~isempty(message1) && length(database2(k,:).Time) > 50
                            
                            plot(ax, [t(index)/3600 t(index)/3600], [-10 10], '--g', 'Linewidth', 1.2);
                            text(ax, t(index) / 3600, voltage(index), 'Range of error', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
                            line(ax, [t(index)/3600, t(index2)/3600], [4.5, 4.5], 'Color', 'r', 'LineWidth', 2);
                            plot(ax, [t(index2)/3600 t(index2)/3600], [-10 10], '--g', 'Linewidth', 1.2);
                          
                        end
                            xlabel(ax, 'Time [hours]');
                            xticks(ax, 0:10:length(t/3600));
                            ylabel(ax, 'Voltage [V] & Current [A]');
                            yticks(ax, -10:1:10);
                            title(ax, ['Cell Formation: ', Name]);
                            text(ax, length(t/3600)*30/2, 0, 'error', 'HorizontalAlignment', 'center','FontSize',10);
                            % Add text labels for Voltage and Current in the top right corner
                            text(ax, max(t/3600) * 1, 0, 'Voltage [V]', 'HorizontalAlignment', 'right', 'FontSize', 12, 'Color', 'b');
                            text(ax, max(t/3600) * 1, -1, 'Current [A]', 'HorizontalAlignment', 'right', 'FontSize', 12, 'Color', 'r');
                    end
                    if isempty(validInx)
                        colors = lines(length(step_kind)); % Generate a colormap
                        plot(ax, t/3600, voltage, 'Color', 'b'); hold(ax, 'on');
                        plot(ax, t/3600, current, 'Color', 'r', 'LineStyle', '-');
                        xlabel(ax, 'Time [hours]');
                        xticks(ax, 0:10:length(t/3600));
                        ylabel(ax, 'Voltage [V] & Current [A]');
                        yticks(ax, -10:1:10);
                        title(ax, ['Cell Formation: ', Name]);
                        text(ax, length(t/3600)*30/2, 0, 'error', 'HorizontalAlignment', 'center','FontSize',10);
                        % Add text labels for Voltage and Current in the top right corner
                        text(ax, max(t/3600) * 1, 0, 'Voltage [V]', 'HorizontalAlignment', 'right', 'FontSize', 12, 'Color', 'b');
                        text(ax, max(t/3600) * 1, -1, 'Current [A]', 'HorizontalAlignment', 'right', 'FontSize', 12, 'Color', 'r');
                    end
                    hold(ax, 'off');
                    
                    % Create the result table and display it in the figure
                    app.test_result = createResultTable(database2(k,:));
                    % uitable('Parent', fig(k), 'Data', table2cell(app.test_result), 'ColumnName', app.test_result.Properties.VariableNames, ...
                    %         'Position', [50, 50, 700, 200], 'ColumnWidth', {100, 100, 100});
                    
                   % Create the main label

                    if plotNeeded == 0
                        % uilabel(fig(k), 'Text', ['Formation is good for: ' , Name], ...
                        %     'Position', [500, 570, 500, 200], 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'FontColor', 'g');
                          uilabel(fig(k), 'Text', ['Formation is good for: ', Name], ...
                        'Position', [500, 570, 500, 200], 'FontSize', 14, 'FontWeight', 'bold', ...
                        'HorizontalAlignment', 'center', 'FontColor', [0, 0.5, 0]);
                          uilabel(fig(k), 'Text', ['The Cell Name: ', Name], ...
                            'Position', [50, 270, 700, 300], 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                    else
                        uilabel(fig(k), 'Text', message1, ...
                            'Position', [50, 270, 800, 200], 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'FontColor', 'r');
                        uilabel(fig(k), 'Text', ['Formation is NOT good for: ', Name], ...
                        'Position', [500, 570, 500, 170], 'FontSize', 14, 'FontWeight', 'bold', ...
                        'HorizontalAlignment', 'center', 'FontColor', 'r');
                    end
                    % Create the message1 label in red

               
                    % Handle the case where plot is not needed
                    % close(fig(k));
                    % app.test_result = (database2(k,:));
                    % uilabel(fig(k), 'Text', ['Formation is good for' , Name newline, 'Efficiency and Capacity Charge: '], ...
                    %         'Position', [20, 260, 420, 30], 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                    
                    uilabel(fig(k), 'Text', 'Results Table Capacity and Efficiency','Position', [20, 200, 400, 30], ... % Position the label above the table
                    'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                    as = uitable(fig(k), 'Data', table2cell(app.test_result), 'ColumnName', {'Cycles', 'Capacity [Ah]', 'Efficiency [%]'}, ...
                            'Position', [20, 20, 400, 180], 'ColumnWidth', {107, 107, 107});

                    fig(k).Name = 'Result Table';
                    style = uistyle('BackgroundColor', 'red', 'FontWeight', 'bold');
                    for i = 1:height(app.test_result)
                        if 99.3 >= app.test_result{i, 3}
                            addStyle(as, style, 'cell', [i, 3]);
                        end
                    end

        end
    
    
   % function tip = plot_error(index,index2,voltage,current,time,Name,texti,fig, plotNumber,k)
   % 
   %     figure(k);
   %      colors = lines(length(step_kind)); % Generate a colormap
   %      plot(time/3600,voltage, 'Color', colors(plotNumber, :)); hold on; 
   %      % hold on;
   %      plot(time/3600,current, 'Color', 'r', 'LineStyle', '-');
   %      plot([time(index)/3600 time(index)/3600],[-2 5],'--g','Linewidth',1.2);
   %      text(time(index) / 3600, voltage(index), 'Range of error', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
   %      line([time(index)/3600,time(index2)/3600], [4.5, 4.5], 'Color', 'r', 'LineWidth', 2);
   %      plot([time(index2)/3600 time(index2)/3600],[-2 5],'--g','Linewidth',1.2);
   %      xlabel('Time [hours]');
   %      xticks(0:10:length(time/3600));
   %      ylabel('Voltage [V] & Current [A]');
   %      yticks(-2:1:7);
   %      title('Cell Formation', Name);
   %      text(length(time/3600)*30/2, 0, 'error', 'HorizontalAlignment', 'center','FontSize',10);
   % 
   %  end

function Efficiency = calc_Efficiency(data)
    % chg = data{strcmp(data.Status, 'CCCV_Chg'), 'Charge_Capacity_mAh_'};
    % disch = data{strcmp(data.Status, 'CC_DChg'), 'Discharge_Capacity_mAh_'};
    % if length(chg) == length(disch)
    %     Efficiency = disch ./ chg;
    % elseif length(chg) > length(disch)  
    %     Efficiency = disch(:) ./ chg(1:length(disch));
    % elseif length(disch) > length(chg)
    %     Efficiency = disch(1:length(chg)) ./ chg(:);
    % end
    s=diff(database2(k,:).Cycle);
    s1=find(s==1);
    s2=zeros(length(s1)+1,1);
    s2(1)=0;
    s2(2:end) = s1;
    cccv_chg_indices = [];
    cc_DCchg_indices = [];
    for i = 1:length(s2)
        if i == length(s2)
            % Handle the last range (from last s2 value to the end of the database2.Status)
            range = s2(i):length(database2(k,:).Status);
        else
            % Handle the normal range (from s2(i) to s2(i+1)-1)
            range = s2(i)+1:s2(i+1);  % Adjust for MATLAB indexing starting at 1
        end
    
        % Find the indices of 'CCCV_Chg' in the current range
        if(range(1)==0)
            range(1)=range(2);
        end
        a = find(strcmp(database2(k,:).Status(range), 'CCCV_Chg'));
        b = find(strcmp(database2(k,:).Status(range), 'CC_DChg'));
        % Convert to the absolute indices in database2.Status
        a_absolute = a + range(1) - 1;
        b_absolute = b + range(1) - 1;
        % Append the found indices to the list
        cccv_chg_indices = [cccv_chg_indices; a_absolute];
        cc_DCchg_indices = [cc_DCchg_indices;b_absolute];
        q=[cccv_chg_indices(1),cc_DCchg_indices(1)];
        q1=[cccv_chg_indices(end),cc_DCchg_indices(end)];
        I=data.Current_mA_/1000;
        % Efficiency(i,3)=(data.Voltage(q1(1,1))-data.Voltage(q(1,2)))/I(cccv_chg_indices(end));
        Capacity_chg(i)=database2(k,:).Charge_Capacity_mAh_(q1(1,1));
        Capacity_discharge(i)=database2(k,:).Discharge_Capacity_mAh_(q1(1,2)); % discharge / charge
        Efficiency(i,1)=Capacity_discharge(i)/Capacity_chg(i)*100;
        Efficiency(i,2)=Capacity_chg(i);
    end
end
function resultTable = createResultTable(data)
    % Find the maximum value in the 'Cycle' column
    maxCycle = max(data.Cycle); %Excel
    % maxCycle = max(data.Cycle); %NDA
    
    % Create a table with variables 'Cycles', 'Capacity', and 'Efficiency'
    resultTable = table('Size', [maxCycle, 3], 'VariableTypes', {'double', 'double', 'double'}, ...
                        'VariableNames', {'Cycles', 'Capacity_mAh', 'Efficiency_pct'});
    
    % Populate the 'Cycles' column with values from 1 to the maximum cycle value
    resultTable.Cycles = (1:maxCycle)';
    if maxCycle <3
        efficiency(:,2) =0;
    else
        efficiency = calc_Efficiency(data);
        % Fill in the 'Capacity' column with the discharge capacity of the last row of this cycle
        % resultTable.Capacity(cycle) = max(cycleData.CapaCity_mAh_(:))/1000;

        if ~isempty(efficiency)
            resultTable.Efficiency_pct = efficiency(:,1);
            resultTable.Capacity_mAh = efficiency(:,2)/1000;
        end
    end
    % Iterate over each cycle to calculate efficiency and fill capacity
    % for cycle = 1:maxCycle
        % Select data for the current cycle
        % cycleData = data(data.CyCle == cycle, :); %Excel
        % s=diff(data.Cycle);
        % a=find(s==1);
        % cycleData = data(data.Cycle( == cycle, :); % NDA
        
        % Calculate efficiency for this cycle
        % efficiency = calc_Efficiency(cycleData);

    % end
end
   
            
        end

        % Callback function
        function compare(app, event)
            
    app.UITable.Data = app.test_result;
    style = uistyle('BackgroundColor', 'red');
    app.EfficiencyEditField_3.Value;app.CapacitymAhEditField_2.Value;
    cap = zeros(1,height(app.test_result));
    eff = zeros(1,height(app.test_result));
    % Create a style object for the red background

    for i=1:height(app.test_result)
        if(app.CapacitymAhEditField_2.Value >= app.test_result{i,2})
            cap(i) = 1;
            addStyle(app.UITable, style, 'cell', [i, 2]);
        end
        if((99.3) >= app.test_result{i,3})
            eff(i) = 1;
            addStyle(app.UITable, style, 'cell', [i, 3]);
        end
    end

    % error_cap = ['The capacity in Cycle number: ',num2str(i),'is not matching the wanted Capacity'];
    % error_effi = ['The Efficiency in Cycle number: ',num2str(i),'is not matching the wanted Efficiency'];
    % good_cap = ['The capacity in Cycle number: ',num2str(i),'is matching the wanted Capacity'];
    % good_effi = ['The Efficiency in Cycle number: ',num2str(i),'is matching the wanted Efficiency'];
    % fig = uifigure('Name','hgtr');
    % fig.Position = [20 100 500 200];
    % uit = uitable(fig,"Data",app.UITable.Data,"Position",[50 50 400 150]);

        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            app.Data = 0;
            app.test_result = 0;
            app.Data = 0;
            app.Chimestry = 0;
            app.Size = 0;
            app.Kind=0;
        end

        % Value changed function: BatteryTypeDropDown
        function dropDownValueChanged(app, event)
           app.NMCGrDropDown_2.Visible=0;
                         % Get the selected value from the drop-down menu
            selectedOption = app.BatteryTypeDropDown.Value;
            
            % NMC-Gr
            % NMC-GrSi
            % LFP-Gr
            % LMFP
             % Check the selected option and open another option accordingly
             switch selectedOption
                 case 'NMC-Gr'
                    app.NMCGrDropDown.Visible=1; 
                    app.Chimestry = 1;
                 case 'NMC-GrSi'
                    app.NMCGrDropDown.Visible=1;
                    app.Chimestry = 2;
                 case 'LFP-Gr'
                    app.NMCGrDropDown.Visible=1; 
                    app.Chimestry = 3;
                 case 'Choose Type'
                    app.NMCGrDropDown.Visible=0; 
             end
        end

        % Value changed function: NMCGrDropDown
        function choosevalue(app, event)
            %value = app.NMCGrDropDown.Value;
            selectedOption_1=app.NMCGrDropDown.Value;
                    switch selectedOption_1
                        case '21700'
                            app.Size =1;
                            app.NMCGrDropDown_2.Visible=0;
                        case '18650'
                            app.Size =2;
                            app.NMCGrDropDown_2.Visible=0;
                        case 'Pouch'
                            app.Size =3;
                            app.NMCGrDropDown_2.Visible=1;
                    end
        end

        % Value changed function: NMCGrDropDown_2
        function choosekind(app, event)
            selectedOption_1=app.NMCGrDropDown_2.Value;
                    switch selectedOption_1
                        case 'JBM'
                            app.Kind =1;
                        case 'Rafael'
                            app.Kind =2;
                        case 'JTI'
                            app.Kind =3;
                        case 'MAFAT'
                            app.Kind =4;
                    end
        end

        % Button pushed function: ChooseFileButton
        function python(app, event)
            

    persistent executablePath;

    if isempty(executablePath) || ~isfile(executablePath)
        % Prompt the user to select the input directory
        inputDir = uigetdir('C:\', 'Select Input Directory');
        
        % Check if the user canceled the directory selection
        if isequal(inputDir, 0)
            msgbox('Directory selection canceled.', 'Error', 'error');
            return;
        end
        
        % Search for the executable in the specified directory
        executablePath = fullfile(inputDir, 'NDA_EXCEL.exe');
        
        % Check if the executable exists
        if ~isfile(executablePath)
            msgbox('Executable not found. Please make sure NDA_EXCEL.exe is in the selected directory.', 'Error', 'error');
            executablePath = [];  % Reset the path
            return;
        end
        
        % Save the path to a file for persistence
        savePathToFile(executablePath);
    else
        % Load the path from the file
        executablePath = loadPathFromFile();
    end
    
    % Command to execute the compiled executable
    command = sprintf('"%s"', executablePath);

    % Execute the command in the system shell
    [status, result] = system(command);

    % Check the status and display the result
    if status == 0
        % Decode the JSON result
        try
            jsonData = jsondecode(result);

            % Extract data, sizes, and errors
            if isfield(jsonData, 'data')
                % Convert struct array to cell array
                dataFields = fieldnames(jsonData.data);
                dataCell = struct2cell(jsonData.data);
                a = cell2struct(dataCell, dataFields, 1);
                app.a1.value = a;
                disp('Data assigned to app.a1');
            else
                disp('Error: JSON data does not contain "data" field.');
            end
            app.files_size = jsonData.sizes;
            app.files_error = jsonData.errors;

            % Display sizes and errors
            sizeInfo = sprintf('File sizes:\n');
            for i = 1:numel(app.files_size)
                sizeInfo = sprintf('%s\nFile: %s\nRows: %d, Columns: %d', sizeInfo, app.files_size(i).file, app.files_size(i).rows, app.files_size(i).columns);
            end
            disp(sizeInfo);  % Display the size information in the MATLAB Command Window
            
            % Display the result in the MATLAB Text Area
            app.OutputTextArea.Value = {'Executable ran successfully.', sizeInfo};

            if ~isempty(app.files_error)
                errorInfo = 'Errors:\n';
                for i = 1:numel(app.files_error)
                    errorInfo = sprintf('%s\nFile: %s\nError: %s', errorInfo, app.files_error(i).file, app.files_error(i).error);
                end
                disp(errorInfo);  % Display the error information in the MATLAB Command Window
                app.OutputTextArea.Value = [app.OutputTextArea.Value; {errorInfo}];
            end
        catch ME
            disp('Error decoding JSON result:');
            disp(ME.message);
            app.OutputTextArea.Value = {'Error decoding JSON result.', ME.message};
        end
    else
        % Display error message with detailed information
        msgbox(sprintf('Error running executable! Status: %d\nResult: %s\nMake sure the executable is correct and has the necessary permissions.', status, result), 'Error Running Executable', 'error');
    end


function savePathToFile(executablePath)
    pathFile = fullfile(tempdir, 'executablePath.mat');
    save(pathFile, 'executablePath');
end

function executablePath = loadPathFromFile()
    pathFile = fullfile(tempdir, 'executablePath.mat');
    if isfile(pathFile)
        s = load(pathFile, 'executablePath');
        executablePath = s.executablePath;
    else
        executablePath = [];
    end
end

            
            % % Ensure the correct Python environment is set
            %  % Ensure the correct Python environment is set and loaded
            % pe = pyenv('Version', 'C:\Users\yuval\AppData\Local\Programs\Python\Python311\python.exe');
            % if pe.Status == "NotLoaded"
            %     pyenv('ExecutionMode', 'InProcess');
            % end
            % 
            % % Set PYTHONPATH environment variable
            % setenv('PYTHONPATH', 'C:\Users\yuval\AppData\Local\Programs\Python\Python311\Lib\site-packages');
            % 
            % % Full path to the Python executable
            % pythonExecutable = 'C:\Users\yuval\AppData\Local\Programs\Python\Python311\python.exe';
            % 
            % % Path to the Python script
            % pythonScriptPath = 'C:\Users\yuval\PycharmProjects\pythonProject\NDA_EXCEL.py';
            % 
            % % Command to execute the Python script
            % command = sprintf('"%s" "%s"', pythonExecutable, pythonScriptPath);
            % 
            % % Execute the command in the system shell
            % [status, result] = system(command);
            % 
            % % Check the status and display the result
            % if status == 0
            %      % Decode the JSON result
            %     jsonData = jsondecode(result);
            % 
            %     % Extract data, sizes, and errors
            % 
            %    if isfield(jsonData, 'data')
            %     % Convert struct array to cell array
            %     dataFields = fieldnames(jsonData.data);
            %     dataCell = struct2cell(jsonData.data);
            %     a = cell2struct(dataCell, dataFields, 1);
            %     app.a1.value=a;
            %     disp('Data assigned to app.a1');
            % else
            %     disp('Error: JSON data does not contain "data" field.');
            % end
            %     app.files_size= jsonData.sizes;
            %     app.files_error= jsonData.errors;
            % 
            % 
            %     % Display sizes and errors
            %     sizeInfo = sprintf('File sizes:\n');
            %     for i = 1:numel(app.files_size)
            %         sizeInfo = sprintf('%s\nFile: %s\nRows: %d, Columns: %d', sizeInfo,  app.files_size(i).file,  app.files_size(i).rows,  app.files_size(i).columns);
            %     end
            %     disp(sizeInfo);  % Display the size information in the MATLAB Command Window
            % 
            %     % Display the result in the MATLAB Text Area
            %     app.OutputTextArea.Value = {'Python script executed successfully.', sizeInfo};
            % 
            %     if ~isempty(app.files_error)
            %         errorInfo = 'Errors:\n';
            %         for i = 1:numel(app.files_error)
            %             errorInfo = sprintf('%s\nFile: %s\nError: %s', errorInfo, app.files_error(i).file, app.files_error(i).error);
            %         end
            %         disp(errorInfo);  % Display the error information in the MATLAB Command Window
            %         app.OutputTextArea.Value = [app.OutputTextArea.Value; {errorInfo}];
            %     end
            % 
            % else
            % h = msgbox('Error Loading file! , make sure the files you are loading are good , if so contact Benel for more details.', 'Error Loading File', 'error');
            % % Find the text object within the message box
            % hText = findall(h, 'Type', 'Text');
            % % Set the font size
            % set(hText, 'FontSize', 10);  % Adjust the font size as needed
            % newPosition = get(h, 'Position');
            % newPosition(3:4) = [400, 220]; % Width and height of the message box window
            % set(h, 'Position', newPosition);
            % 
            % end
        % $$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$ DELETE THIS SHIT ITS ONLY FOR TESTING  $$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$
            % [filenames, pathname] = uigetfile('*.*', 'Select files', 'MultiSelect', 'on');
            % % Check if the user clicked Cancel in the file dialog
            % if isequal(filenames, 0) || isequal(pathname, 0)
            %     msgbox('File selection canceled.');
            %     return; % Exit the script if the user cancels file selection
            % end
            % 
            % % Handle the case where only one file is selected
            % if ischar(filenames)
            %     filenames = {filenames};
            % end
            % 
            % % Initialize cell arrays to store data
            % data = {};
            % data2 = {};
            % data3 = {};
            % 
            % % Iterate over each selected file
            % for i = 1:numel(filenames)
            %     fullFilePath = fullfile(pathname, filenames{i});
            % 
            %     % Read all sheets in the Excel file
            %     [~, sheetNames] = xlsfinfo(fullFilePath);
            % 
            %     % Iterate over each sheet
            %     for j = 1:numel(sheetNames)
            %         % Check if the sheet name starts with 'Detail_'
            %         if startsWith(sheetNames{j}, 'Detail_')
            %             % Read data from the sheet
            %             data{end+1} = readtable(fullFilePath, 'Sheet', sheetNames{j});
            %         elseif startsWith(sheetNames{j}, 'Statis_')
            %             % Read data from the corresponding 'Statis_' sheet
            %             data2{end+1} = readtable(fullFilePath, 'Sheet', sheetNames{j});
            %         end
            %     end
            %     sheet3 = 'Info';
            %     data3{end+1} = readtable(fullFilePath, 'Sheet', sheet3);
            % end
            % 
            % app.Data = {data, data2, data3};
        % $$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '3DB.png');
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @python, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @Analyze, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Formation Checker 1.0 '; 'This program is processing and analyzing Formation Data. '; '                              Please Choose the Battery Cell'};
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'battery.png');
            app.ProcessTypeDropDownLabel = uilabel(app.UIFigure);
            app.ProcessTypeDropDownLabel.Text = 'Process Type:';
            app.ProcessTypeDropDown = uidropdown(app.UIFigure);
            app.ProcessTypeDropDown.Items = {'Formation', 'Cycling'};
            app.ProcessTypeDropDown.Value = 'Formation';
            app.BatteryTypeDropDownLabel = uilabel(app.UIFigure);
            app.BatteryTypeDropDownLabel.Text = 'Battery Type:';
            app.BatteryTypeDropDown = uidropdown(app.UIFigure);
            app.BatteryTypeDropDown.Items = {'Choose Type', 'NMC-Gr', 'NMC-GrSi', 'LFP-Gr', 'LMFP'};
            app.BatteryTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownValueChanged, true);
            app.BatteryTypeDropDown.Value = 'Choose Type';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            app.NMCGrDropDownLabel = uilabel(app.UIFigure);
            app.NMCGrDropDownLabel.Visible = 'off';
            app.NMCGrDropDownLabel.Text = 'NMC-Gr';
            app.NMCGrDropDown = uidropdown(app.UIFigure);
            app.NMCGrDropDown.Items = {'Choose Size', '21700', '18650', 'Pouch'};
            app.NMCGrDropDown.ValueChangedFcn = createCallbackFcn(app, @choosevalue, true);
            app.NMCGrDropDown.Visible = 'off';
            app.NMCGrDropDown.Value = 'Choose Size';
            app.NMCGrDropDown_2 = uidropdown(app.UIFigure);
            app.NMCGrDropDown_2.Items = {'Choose kind', 'JBM', 'Rafael', 'JTI', 'MAFAT'};
            app.NMCGrDropDown_2.ValueChangedFcn = createCallbackFcn(app, @choosekind, true);
            app.NMCGrDropDown_2.Visible = 'off';
            app.NMCGrDropDown_2.Value = 'Choose kind';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.OutputTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_checker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end